classdef AO2NWB_v01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AO2NWBUIFigure                matlab.ui.Figure
        CreateNWBPanel                matlab.ui.container.Panel
        generateNWB                   matlab.ui.control.Button
        AOFileinformationPanel        matlab.ui.container.Panel
        CaseInfoSwitch                matlab.ui.control.Switch
        CaseInfoSwitchLabel           matlab.ui.control.Label
        hemiDD                        matlab.ui.control.DropDown
        HemisphereDropDownLabel       matlab.ui.control.Label
        DxDD                          matlab.ui.control.DropDown
        DxDropDownLabel               matlab.ui.control.Label
        btDD                          matlab.ui.control.DropDown
        BraintargetDropDownLabel      matlab.ui.control.Label
        SurgeryEditField              matlab.ui.control.NumericEditField
        SurgeryEditFieldLabel         matlab.ui.control.Label
        SideEditField                 matlab.ui.control.NumericEditField
        SideEditFieldLabel            matlab.ui.control.Label
        dos                           matlab.ui.control.DatePicker
        DateofsurgeryDatePickerLabel  matlab.ui.control.Label
        FoldernameEditField           matlab.ui.control.EditField
        FoldernameEditFieldLabel      matlab.ui.control.Label
        DirectorylocationsPanel       matlab.ui.container.Panel
        NWBSaveEditField              matlab.ui.control.EditField
        NWBSaveEditFieldLabel         matlab.ui.control.Label
        AODirectoryEditField          matlab.ui.control.EditField
        AODirectoryEditFieldLabel     matlab.ui.control.Label
        SETButton                     matlab.ui.control.Button
        FileOptionsPanel              matlab.ui.container.Panel
        loadAO_folder                 matlab.ui.control.Button
    end

    
    %%%% TO DO
    % 1. Currently working on combining duplicated files
    % 2. Test on one or two cases
    % 3. Finalize packaging for MG cases
    
    properties (Access = private)
        aoDIRloc % Description
        caseFoldName
        nwbLOC
        machine
    end
    
    methods (Access = private)
        
        function prepAO(app)
            
            cd(app.aoDIRloc)
            % Get list of .mat files
            matdirL = dir('*.mat');
            matLIST = {matdirL.name};
            
            fnameLens = cellfun(@(x) length(x) , matLIST, 'UniformOutput',true);
            
            % Get length of file names
            fnMlen = max(fnameLens);
            % Logical vector of '-' prefixes present
            negPreFix = cellfun(@(x) contains(x,'-'), matLIST, 'UniformOutput',true);
            % Set app setting for whether recording machine was NeuroOmega
            % or MicroGuide
            if any(negPreFix) || fnMlen <= 13
                app.machine = 'MG';
            else
                app.machine = 'NO';
            end
            
            if strcmp(app.machine,'MG')

                % Rename negative files
                dashLIST = find(cellfun(@(x) contains(x,'-'), matLIST, 'UniformOutput',true));
                nonDashLIST = find(cellfun(@(x) ~contains(x,'-'), matLIST, 'UniformOutput',true));
                % Negative
                for di = 1:length(dashLIST)

                    movefile(matLIST{dashLIST(di)} , ['n',matLIST{dashLIST(di)}(2:end)]);

                end
                % Positive
                for ndi = 1:length(nonDashLIST)
                    
                    if ismember(matLIST{nonDashLIST(ndi)}(1) , {'n','p'})
                        continue
                    else
                        movefile(matLIST{nonDashLIST(ndi)} , ['p',matLIST{nonDashLIST(ndi)}]);
                    end
                   
                end

            end
            
            % Check what to do with duplicates
            switch app.machine
                case 'MG'
                    % New file name list
                    matdirL2 = dir('*.mat');
                    matLIST2 = {matdirL2.name};
                    % Trim first 
                    trimN = cellfun(@(x) x(1:6), matLIST2,'UniformOutput',false);
                    
                    [uniNas,~,indicies] = unique(trimN);
                    uniCounts = histcounts(indicies,length(uniNas));
                    dupCses = uniNas(uniCounts ~= 1);
                    
                    for dp = 1:length(dupCses)
                        
                        tmpDP = dupCses{dp};
                        
                        TDPlocs = matLIST2(contains(matLIST2,tmpDP));
                        
                        % Collect and combine
                        % Assume shorter fname is first in time
                        
                        % Question - are files separated in time or new
                        % recordings
                        
                        % Sort 
                        % Short file 
                        shortFns = TDPlocs(cellfun(@(x) length(x), TDPlocs, 'UniformOutput',true) < 11);
                        % Then numerics
                        longFNs = TDPlocs(cellfun(@(x) length(x), TDPlocs, 'UniformOutput',true) > 11);
                        
                        dupLIST = [shortFns , longFNs];
                        
                        for ti = 1:length(dupLIST)
                            t1 = dupLIST(ti);
                            t2 = dupLIST(ti + 1);
                            t1_E = matFileLoad(app , t1 , 'CElectrode1_TimeEnd');
                            t2_B = matFileLoad(app , t2 , 'CElectrode1_TimeBegin');
                            t_SF = matFileLoad(app , t1 , 'CElectrode1_kHz');
                            
                            timeDIF = t2_B{1} - t1_E{1};
                            
                            if timeDIF*(t_SF*1000) < 1.1
                                % Add data to previous file
                                
                                % Remove current file name 
                                
                                
                            end
                            
                            if ti == length(dupLIST)-1
                                break
                            end
                            
                        end
                        
                        
                    end


            end
            
            
            % Check mat dimesions of CElectrode , CLFP - with matfile ,
            % emg, accel
            
            % Get start time in seconds from top file
            
            % Create matrices
            
            % Collect samp frequency
            
        end
        
        function fieldOUTs = matFileLoad(~ , matNAMEs , fieldOI)
            
            fieldOUTs = cell(length(matNAMEs),1);
            for mi = 1:length(matNAMEs)
                tmpMat = matfile(matNAMEs{mi});
                
                fieldOUTs{mi} = tmpMat.(fieldOI);
                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadAO_folder
        function loadAO_folderButtonPushed(app, event)
            
            [app.aoDIRloc] = uigetdir();
            
            cd(app.aoDIRloc)
            
            [~,app.caseFoldName,~] = fileparts(app.aoDIRloc);
            
            app.AODirectoryEditField.Value = app.aoDIRloc;
            
            app.DirectorylocationsPanel.Enable = 'on';
            
            app.SETButton.Enable = 'on';
            
        end

        % Button pushed function: SETButton
        function SETButtonPushed(app, event)
            
            [app.nwbLOC] = uigetdir();
            app.NWBSaveEditField.Value = app.nwbLOC;
            
            app.AOFileinformationPanel.Enable = 'on';
            
            app.FoldernameEditField.Value = app.caseFoldName;
            
            app.SETButton.Enable = 'off';
        end

        % Value changed function: CaseInfoSwitch
        function CaseInfoSwitchValueChanged(app, event)
            value = app.CaseInfoSwitch.Value;
            
            switch value
                case 'Accept'
                    app.dos.Enable = 'off';
                    app.SurgeryEditField.Enable = 'off';
                    app.DxDD.Enable = 'off';
                    app.hemiDD.Enable = 'off';
                    app.btDD.Enable = 'off';
                    app.SideEditField.Enable = 'off';
                    
                    app.CreateNWBPanel.Enable = 'on';
                    
                case 'Hold'
                    app.dos.Enable = 'on';
                    app.SurgeryEditField.Enable = 'on';
                    app.DxDD.Enable = 'on';
                    app.hemiDD.Enable = 'on';
                    app.btDD.Enable = 'on';
                    app.SideEditField.Enable = 'on';
                    
                    app.CreateNWBPanel.Enable = 'off';

            end
            
        end

        % Button pushed function: generateNWB
        function generateNWBButtonPushed(app, event)

            
            % Prepare data info
            prepAO(app)


            curSess = app.sessLOGdat{app.curROW};

            % Check if it is behavior or resting state
            cd(app.nwbLOC)

            % From data
            identifier = datestr(app.dos);
            timeStamP = curSess.SessionInfo.ts(curSess.StartIndex);
            sessionstarttime = datetime(timeStamP/1000000,'ConvertFrom',...
                'posixtime','TimeZone','America/Denver');

            generateCore();

            % Create NWB with session and subject info
            nwb = NwbFile( ...
                'session_description', 'EMU patient',...
                'identifier', [identifier ,'_', num2str(app.curROW)], ...
                'session_start_time', sessionstarttime, ...
                'general_experimenter', app.ExperimenterDropDown.Value, ...
                'general_session_id', app.SessionIDDropDown.Value, ...
                'general_institution', 'UC Anschutz');

            subject = types.core.Subject(...
                'subject_id', identifier, ...
                'age', ['P',num2str(app.SubAgeEditField.Value),'Y'], ...
                'sex',app.SubSexDropDown.Value);

            nwb.general_subject = subject;

            device = types.core.Device(...
                'description', 'Atlas', ...
                'manufacturer', 'Neuralynx');
            device_name = 'NLX';

            nwb.general_devices.set(device_name, device);
            device_link = types.untyped.SoftLink(['/general/devices/' device_name]);

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AO2NWBUIFigure = uifigure('Visible', 'off');
            app.AO2NWBUIFigure.Name = 'AO2NWB';
            app.AO2NWBUIFigure.Icon = 'appICON.png';
            app.FileOptionsPanel = uipanel(app.AO2NWBUIFigure);
            app.FileOptionsPanel.Title = 'File Options';
            app.loadAO_folder = uibutton(app.FileOptionsPanel, 'push');
            app.loadAO_folder.ButtonPushedFcn = createCallbackFcn(app, @loadAO_folderButtonPushed, true);
            app.loadAO_folder.Icon = 'FolderOpenIcon.png';
            app.loadAO_folder.Text = '';
            app.DirectorylocationsPanel = uipanel(app.AO2NWBUIFigure);
            app.DirectorylocationsPanel.Enable = 'off';
            app.DirectorylocationsPanel.Title = 'Directory locations';
            app.SETButton = uibutton(app.DirectorylocationsPanel, 'push');
            app.SETButton.ButtonPushedFcn = createCallbackFcn(app, @SETButtonPushed, true);
            app.SETButton.Text = 'SET';
            app.AODirectoryEditFieldLabel = uilabel(app.DirectorylocationsPanel);
            app.AODirectoryEditFieldLabel.Text = 'AO Directory';
            app.AODirectoryEditField = uieditfield(app.DirectorylocationsPanel, 'text');
            app.AODirectoryEditField.Editable = 'off';
            app.NWBSaveEditFieldLabel = uilabel(app.DirectorylocationsPanel);
            app.NWBSaveEditFieldLabel.Text = 'NWB Save';
            app.NWBSaveEditField = uieditfield(app.DirectorylocationsPanel, 'text');
            app.NWBSaveEditField.Editable = 'off';
            app.AOFileinformationPanel = uipanel(app.AO2NWBUIFigure);
            app.AOFileinformationPanel.Enable = 'off';
            app.AOFileinformationPanel.Title = 'AO File information';
            app.FoldernameEditFieldLabel = uilabel(app.AOFileinformationPanel);
            app.FoldernameEditFieldLabel.Text = 'Folder name';
            app.FoldernameEditField = uieditfield(app.AOFileinformationPanel, 'text');
            app.FoldernameEditField.Editable = 'off';
            app.DateofsurgeryDatePickerLabel = uilabel(app.AOFileinformationPanel);
            app.DateofsurgeryDatePickerLabel.Text = 'Date of surgery';
            app.dos = uidatepicker(app.AOFileinformationPanel);
            app.SideEditFieldLabel = uilabel(app.AOFileinformationPanel);
            app.SideEditFieldLabel.Text = 'Side #';
            app.SideEditField = uieditfield(app.AOFileinformationPanel, 'numeric');
            app.SurgeryEditFieldLabel = uilabel(app.AOFileinformationPanel);
            app.SurgeryEditFieldLabel.Text = 'Surgery #';
            app.SurgeryEditField = uieditfield(app.AOFileinformationPanel, 'numeric');
            app.BraintargetDropDownLabel = uilabel(app.AOFileinformationPanel);
            app.BraintargetDropDownLabel.Text = 'Brain target';
            app.btDD = uidropdown(app.AOFileinformationPanel);
            app.btDD.Items = {'STN', 'GPi', 'VIM', 'VCVS'};
            app.btDD.Value = 'STN';
            app.DxDropDownLabel = uilabel(app.AOFileinformationPanel);
            app.DxDropDownLabel.Text = 'Dx';
            app.DxDD = uidropdown(app.AOFileinformationPanel);
            app.DxDD.Items = {'PD', 'ET', 'DYS', 'OCD'};
            app.DxDD.Value = 'PD';
            app.HemisphereDropDownLabel = uilabel(app.AOFileinformationPanel);
            app.HemisphereDropDownLabel.Text = 'Hemisphere';
            app.hemiDD = uidropdown(app.AOFileinformationPanel);
            app.hemiDD.Items = {'Left', 'Right'};
            app.hemiDD.Value = 'Left';
            app.CaseInfoSwitchLabel = uilabel(app.AOFileinformationPanel);
            app.CaseInfoSwitchLabel.Text = 'Case Info';
            app.CaseInfoSwitch = uiswitch(app.AOFileinformationPanel, 'slider');
            app.CaseInfoSwitch.Items = {'Accept', 'Hold'};
            app.CaseInfoSwitch.ValueChangedFcn = createCallbackFcn(app, @CaseInfoSwitchValueChanged, true);
            app.CaseInfoSwitch.Value = 'Hold';
            app.CreateNWBPanel = uipanel(app.AO2NWBUIFigure);
            app.CreateNWBPanel.Enable = 'off';
            app.CreateNWBPanel.Title = 'Create NWB';
            app.generateNWB = uibutton(app.CreateNWBPanel, 'push');
            app.generateNWB.ButtonPushedFcn = createCallbackFcn(app, @generateNWBButtonPushed, true);
            app.generateNWB.Icon = 'appICON.png';
            app.generateNWB.Text = '';
            % Show the figure after all components are created
            app.AO2NWBUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AO2NWB_v01

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AO2NWBUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.AO2NWBUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AO2NWBUIFigure)
        end
    end
end